classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FrameAnalysisinRealTimeRTFAUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Main                            matlab.ui.container.Tab
        TripleAxisPlotterPanel          matlab.ui.container.Panel
        UIAxesTripleMain                matlab.ui.control.UIAxes
        UIAxesTripleLeft                matlab.ui.control.UIAxes
        PlotfunctionDropDownLabel       matlab.ui.control.Label
        PlotFunctionTripleAxisDropDown  matlab.ui.control.DropDown
        ResetZoomTripleAxisButton       matlab.ui.control.Button
        UIAxesTripleBottom              matlab.ui.control.UIAxes
        SingleAxisPlotterPanel          matlab.ui.container.Panel
        UIAxesSingle                    matlab.ui.control.UIAxes
        PlotfunctionDropDown_2Label     matlab.ui.control.Label
        PlotFunctionSingleAxisDropDown  matlab.ui.control.DropDown
        ResetZoomSingleAxisButton       matlab.ui.control.Button
        SnapShotSingleAxisFigureButton  matlab.ui.control.Button
        ControlPanel                    matlab.ui.container.Panel
        PauseButton                     matlab.ui.control.Button
        StartRecordingButton            matlab.ui.control.Button
        PlayButton                      matlab.ui.control.StateButton
        StatusSettingsPanel             matlab.ui.container.Panel
        PlotrateLabel                   matlab.ui.control.Label
        PlotRateEditField               matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        RecordingtimeEditFieldLabel     matlab.ui.control.Label
        RecordingTimeEditField          matlab.ui.control.NumericEditField
        RecordedFramesEditFieldLabel    matlab.ui.control.Label
        RecordedFramesEditField         matlab.ui.control.NumericEditField
        LatencysEditFieldLabel          matlab.ui.control.Label
        LatencysEditField               matlab.ui.control.NumericEditField
        SettingsTab                     matlab.ui.container.Tab
        SpecificCameraSettings          matlab.ui.container.TabGroup
        ZylaSettings                    matlab.ui.container.Tab
        ZylasettingsPanel               matlab.ui.container.Panel
        SpuriousNoiseFilterCheckBox     matlab.ui.control.CheckBox
        OverlapReadoutCheckBox          matlab.ui.control.CheckBox
        AOILabel                        matlab.ui.control.Label
        ApplyAOIButton                  matlab.ui.control.Button
        ResetAOIButton                  matlab.ui.control.Button
        PerformanceLamp                 matlab.ui.control.Lamp
        ExposuretimeEditFieldLabel      matlab.ui.control.Label
        ExposureTimeEditField           matlab.ui.control.NumericEditField
        XpositionSpinner_2Label         matlab.ui.control.Label
        AOIXpositionSpinner             matlab.ui.control.Spinner
        WidthSpinner_2Label             matlab.ui.control.Label
        AOIWidthSpinner                 matlab.ui.control.Spinner
        YpositionSpinner_3Label         matlab.ui.control.Label
        AOIYpositionSpinner             matlab.ui.control.Spinner
        HeightSpinner_2Label            matlab.ui.control.Label
        AOIHeightSpinner                matlab.ui.control.Spinner
        FramerateEditFieldLabel         matlab.ui.control.Label
        FramerateEditField              matlab.ui.control.NumericEditField
        NumberofaccumulationsEditFieldLabel  matlab.ui.control.Label
        NumberOfAccumulationsEditField  matlab.ui.control.NumericEditField
        PixelreadoutrateDropDownLabel   matlab.ui.control.Label
        PixelReadoutRateDropDown        matlab.ui.control.DropDown
        ElectronicshutteringmodeDropDownLabel  matlab.ui.control.Label
        ElectronicShutteringModeDropDown  matlab.ui.control.DropDown
        SensitivityDynamicrangeDropDownLabel  matlab.ui.control.Label
        SensitivityDynamicRangeDropDown  matlab.ui.control.DropDown
        VerticalbinningEditFieldLabel   matlab.ui.control.Label
        VerticalBinningEditField        matlab.ui.control.NumericEditField
        HorisontalbinningEditFieldLabel  matlab.ui.control.Label
        HorisontalBinningEditField      matlab.ui.control.NumericEditField
        TestwritespeedButton            matlab.ui.control.Button
        TriggermodeDropDownLabel        matlab.ui.control.Label
        TriggermodeDropDown             matlab.ui.control.DropDown
        PresetsLabel                    matlab.ui.control.Label
        PresetsListBox                  matlab.ui.control.ListBox
        iXonSettings                    matlab.ui.container.Tab
        iXonsettingsPanel               matlab.ui.container.Panel
        CoolerDropDownLabel             matlab.ui.control.Label
        CoolerDropDown                  matlab.ui.control.DropDown
        ExposuretimeEditFieldLabel_2    matlab.ui.control.Label
        ExposureTimeiXonEditField       matlab.ui.control.NumericEditField
        CoolermodeDropDownLabel         matlab.ui.control.Label
        CoolermodeiXonDropDown          matlab.ui.control.DropDown
        SensortemperatureEditFieldLabel  matlab.ui.control.Label
        SensortemperatureiXonEditField  matlab.ui.control.NumericEditField
        EmgainmodeDropDownLabel         matlab.ui.control.Label
        EmgainmodeiXonDropDown          matlab.ui.control.DropDown
        EmCCDgainEditFieldLabel         matlab.ui.control.Label
        EmCCDgainiXonEditField          matlab.ui.control.NumericEditField
        FramerateEditField_2Label       matlab.ui.control.Label
        FramerateiXonEditField          matlab.ui.control.NumericEditField
        NumberofaccumulationsEditFieldLabel_2  matlab.ui.control.Label
        NumberofaccumulationsiXonEditField  matlab.ui.control.NumericEditField
        VerticalbinningEditFieldLabel_2  matlab.ui.control.Label
        VerticalbinningiXonEditField    matlab.ui.control.NumericEditField
        HorisontalbinningEditFieldLabel_2  matlab.ui.control.Label
        HorisontalbinningiXonEditField  matlab.ui.control.NumericEditField
        AreaofinterestLabeliXon         matlab.ui.control.Label
        XpositionSpinner_2Label_2       matlab.ui.control.Label
        AOIXpositioniXonSpinner         matlab.ui.control.Spinner
        YpositionSpinner_2Label         matlab.ui.control.Label
        AOIYpositioniXonSpinner         matlab.ui.control.Spinner
        WidthSpinner_2Label_2           matlab.ui.control.Label
        AOIWidthiXonSpinner             matlab.ui.control.Spinner
        HeightSpinner_2Label_2          matlab.ui.control.Label
        AOIHeightiXonSpinner            matlab.ui.control.Spinner
        ResetAOIiXonButton              matlab.ui.control.Button
        ApplyAOIiXonButton              matlab.ui.control.Button
        TestWritespeediXonLamp          matlab.ui.control.Lamp
        TestwritespeediXonButton        matlab.ui.control.Button
        SimulatorSettings               matlab.ui.container.Tab
        CamerasimulatorsettingsPanel    matlab.ui.container.Panel
        NewtonSettings                  matlab.ui.container.Tab
        NewtonsettingsPanel             matlab.ui.container.Panel
        AreaofinterestLabelNewton       matlab.ui.control.Label
        ResetAOINewtonButton            matlab.ui.control.Button
        ApplyAOINewtonButton            matlab.ui.control.Button
        TestWritespeedNewtonLamp        matlab.ui.control.Lamp
        TestwritespeedNewtonButton      matlab.ui.control.Button
        CoolerDropDown_2Label           matlab.ui.control.Label
        CoolerNewtonDropDown            matlab.ui.control.DropDown
        ExposuretimeEditFieldLabel_3    matlab.ui.control.Label
        ExposureTimeNewtonEditField     matlab.ui.control.NumericEditField
        CoolermodeDropDownLabel_2       matlab.ui.control.Label
        CoolermodeNewtonDropDown        matlab.ui.control.DropDown
        SensortemperatureEditFieldLabel_2  matlab.ui.control.Label
        SensortemperatureNewtonEditField  matlab.ui.control.NumericEditField
        FramerateEditField_2Label_2     matlab.ui.control.Label
        FramerateNewtonEditField        matlab.ui.control.NumericEditField
        NumberofaccumulationsEditFieldLabel_3  matlab.ui.control.Label
        NumberofaccumulationsNewtonEditField  matlab.ui.control.NumericEditField
        VerticalbinningEditFieldLabel_3  matlab.ui.control.Label
        VerticalbinningNewtonEditField  matlab.ui.control.NumericEditField
        HorisontalbinningEditFieldLabel_3  matlab.ui.control.Label
        HorisontalbinningNewtonEditField  matlab.ui.control.NumericEditField
        XpositionSpinner_2Label_3       matlab.ui.control.Label
        AOIXpositionNewtonSpinner       matlab.ui.control.Spinner
        YpositionSpinner_2Label_2       matlab.ui.control.Label
        AOIYpositionNewtonSpinner       matlab.ui.control.Spinner
        WidthSpinner_2Label_3           matlab.ui.control.Label
        AOIWidthNewtonSpinner           matlab.ui.control.Spinner
        HeightSpinner_2Label_3          matlab.ui.control.Label
        AOIHeightNewtonSpinner          matlab.ui.control.Spinner
        SensingRegionsPanel             matlab.ui.container.Panel
        UIAxesSensingRegionsSettings    matlab.ui.control.UIAxes
        DeleteRegionButton              matlab.ui.control.Button
        RefreshSensingRegionButton      matlab.ui.control.Button
        ResetZoomSensingRegionAxesButton  matlab.ui.control.Button
        IsReferenceRegionCheckBox       matlab.ui.control.CheckBox
        IsBackgroundRegionCheckBox      matlab.ui.control.CheckBox
        SelectregionLabel               matlab.ui.control.Label
        SelectRegionDropDown            matlab.ui.control.DropDown
        WidthLabel                      matlab.ui.control.Label
        WidthSpinner                    matlab.ui.control.Spinner
        HeightLabel                     matlab.ui.control.Label
        HeightSpinner                   matlab.ui.control.Spinner
        XpositionLabel                  matlab.ui.control.Label
        XpositionSpinner                matlab.ui.control.Spinner
        YpositionLabel                  matlab.ui.control.Label
        YpositionSpinner                matlab.ui.control.Spinner
        ReferenceLabel                  matlab.ui.control.Label
        ReferenceRegionsListBox         matlab.ui.control.ListBox
        BackgroundLabel                 matlab.ui.control.Label
        BackgroundRegionsListBox        matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        paths = addpath('assets/')
        Camera
        CameraName
        SensingRegions
        SensingRegionSettingsFrame
        IsRecording
        AreaOfInterest
        Timer = timer
        FileWriter
        LiveAnalyzerSingleAxis
        LiveAnalyzerTripleAxis
        UserCustomisation
        PerformanceTester
        nRecordedFrames
        ShouldBeAcquiringFrames
        StartTimeOfAcquisition
        TimeOfLastAcquisition = 0;
        TimeOfLastPlotting = 0;
        nAcquiredFrames
    end
    
    methods (Access = private)
        
        function togglePlayPauseButtonText(app)
            if isequal(app.StartButton.Text,'Start')
                app.StartButton.Text = 'Pause';
            else
                app.StartButton.Text = 'Start';
            end
        end
        
        function UpdateRegionSettingsGui(app)
            app.UpdateIsReferenceCheckBoxes();
            app.UpdateSettingsRegionPlot();
            app.UpdateSpinnerValues();
            app.UpdateRegionListBoxes();
        end
        
        function PlotSettingsRegionFrame(app)
            image(app.UIAxesSensingRegionsSettings,'xdata',app.Camera.imageLeftPosition+0.5,'ydata',app.Camera.imageTopPosition+0.5,'cdata',app.SensingRegionSettingsFrame,'CDataMapping','scaled');
        end
        
        function PlotAreaOfInterest(app)
            [x,y] = app.AreaOfInterest.getFillCoordinates();
            patch(app.UIAxesSensingRegionsSettings,x,y,'k','FaceAlpha',0);
        end
        
        function PlotSelectedRegion(app)
            region = app.SensingRegions.getRegion(app.SelectRegionDropDown.Value)
            [x,y] = region.getFillCoordinates();
            patch(app.UIAxesSensingRegionsSettings,x,y,'r','FaceAlpha',0.4);
        end
        
        function PlotAssociatedRegions(app)
            selectedRegion = app.SensingRegions.getRegion(app.SelectRegionDropDown.Value)
            if isequal(selectedRegion.type,'signal')
                referenceNames = app.SensingRegions.getNamesOfAssociatedReferenceRegions(app.SelectRegionDropDown.Value);
                for n=1:length(referenceNames)
                    [x,y] = app.SensingRegions.getRegionFillCoordinates(referenceNames{n});
                    patch(app.UIAxesSensingRegionsSettings,x,y,'b','FaceAlpha',0.3);
                end
                backgroundNames = app.SensingRegions.getNamesOfAssociatedBackgroundRegions(app.SelectRegionDropDown.Value);
                for n=1:length(backgroundNames)
                    [x,y] = app.SensingRegions.getRegionFillCoordinates(backgroundNames{n});
                    patch(app.UIAxesSensingRegionsSettings,x,y,'k','FaceAlpha',0.3);
                end
            end
        end
        
        function PlotSensingRegions(app)
            if ~isempty(app.SelectRegionDropDown.Value)
                app.PlotSelectedRegion();
                app.PlotAssociatedRegions();
            end
        end
        
        function PlotSensorRegion(app)
            sensorCoordinates = app.Camera.sensorCoordinates;
            x = [sensorCoordinates(:,1);sensorCoordinates(1,1)];
            y = [sensorCoordinates(:,2);sensorCoordinates(1,2)];
            patch(app.UIAxesSensingRegionsSettings,x,y,'w','EdgeColor','w','FaceAlpha',1);
        end
        
        function UpdateSettingsRegionPlot(app)
            cla(app.UIAxesSensingRegionsSettings);
            hold(app.UIAxesSensingRegionsSettings,'on');
            app.PlotSensorRegion();
            app.PlotSettingsRegionFrame();
            app.PlotAreaOfInterest();
            app.PlotSensingRegions();
        end
        
        function UpdateSpinnerValues(app)
            if ~isempty(app.SelectRegionDropDown.Value)
                region = app.SensingRegions.getRegion(app.SelectRegionDropDown.Value)
                app.XpositionSpinner.Value = region.xPosition + region.xOffset;
                app.YpositionSpinner.Value = region.yPosition + region.yOffset;
                app.WidthSpinner.Value = region.width;
                app.HeightSpinner.Value = region.height;
                app.enableRegionSpinners();
            else
                app.disableRegionSpinners();
            end
        end
        
        function UpdateRegionListBoxes(app)
            selectedRegion = app.SelectRegionDropDown.Value;
            if app.SensingRegions.regionNameExists(selectedRegion) && isequal(app.SensingRegions.getRegionType(selectedRegion),'signal')
                app.enableReferenceListBoxes();
                app.ReferenceRegionsListBox.Items = app.SensingRegions.namesOfReferenceRegions;
                app.ReferenceRegionsListBox.Value = app.SensingRegions.getNamesOfAssociatedReferenceRegions(selectedRegion);
                app.BackgroundRegionsListBox.Items = app.SensingRegions.namesOfBackgroundRegions;
                app.BackgroundRegionsListBox.Value = app.SensingRegions.getNamesOfAssociatedBackgroundRegions(selectedRegion);
            else
                app.disableAndEmptyReferenceListBoxes();
            end
        end
        
        function disableAndEmptyReferenceListBoxes(app)
            app.ReferenceRegionsListBox.Items = {''};
            app.BackgroundRegionsListBox.Items = {''};
            app.ReferenceRegionsListBox.Value = {};
            app.BackgroundRegionsListBox.Value = {};
            app.ReferenceRegionsListBox.Enable = 'off';
            app.BackgroundRegionsListBox.Enable = 'off';
        end
        
        function enableReferenceListBoxes(app)
            app.ReferenceRegionsListBox.Enable = 'on';
            app.BackgroundRegionsListBox.Enable = 'on';
        end
        
        
        function enableRegionSpinners(app)
            app.HeightSpinner.Enable = 'on';
            app.WidthSpinner.Enable = 'on';
            app.XpositionSpinner.Enable = 'on';
            app.YpositionSpinner.Enable = 'on';
        end
        
        function disableRegionSpinners(app)
            app.HeightSpinner.Enable = 'off';
            app.WidthSpinner.Enable = 'off';
            app.XpositionSpinner.Enable = 'off';
            app.YpositionSpinner.Enable = 'off';
        end
        
        function UpdateIsReferenceCheckBoxes(app)
            if ~isempty(app.SelectRegionDropDown.Value)
                app.IsReferenceRegionCheckBox.Value = isequal(app.SensingRegions.getRegionType(app.SelectRegionDropDown.Value),'reference');
                app.IsBackgroundRegionCheckBox.Value = isequal(app.SensingRegions.getRegionType(app.SelectRegionDropDown.Value),'background');
                app.enableIsReferenceCheckBoxes();
            else
                app.disableIsReferenceCheckBoxes();
                app.IsReferenceRegionCheckBox.Value = false;
            end
        end
        
        function enableIsReferenceCheckBoxes(app)
            app.IsBackgroundRegionCheckBox.Enable = true;
            app.IsReferenceRegionCheckBox.Enable = true;
        end
        
        function disableIsReferenceCheckBoxes(app)
            app.IsBackgroundRegionCheckBox.Enable = false;
            app.IsReferenceRegionCheckBox.Enable = false;
        end
        
        function addNameToSelectRegionDropDown(app,name)
            app.SelectRegionDropDown.Items = app.SensingRegions.namesOfAllRegions
            app.SelectRegionDropDown.Items{end+1} = 'Add region';
            app.SelectRegionDropDown.Value = name;
        end
        
        function results = UpdateRegionAssociations(app)
            app.SensingRegions.removeAssociationsOfRegion(app.SelectRegionDropDown.Value);
            for n=1:length(app.ReferenceRegionsListBox.Value)
                app.SensingRegions.associateRegionWithSignal(app.ReferenceRegionsListBox.Value{n},app.SelectRegionDropDown.Value);
            end
            for n=1:length(app.BackgroundRegionsListBox.Value)
                app.SensingRegions.associateRegionWithSignal(app.BackgroundRegionsListBox.Value{n},app.SelectRegionDropDown.Value);
            end
        end
        
        function InitialiseAreaOfInterest(app)
            app.AreaOfInterest = Region();
            
            app.AreaOfInterest.height = app.Camera.imageHeight;
            app.AreaOfInterest.width = app.Camera.imageWidth;
            app.AreaOfInterest.yPosition = app.Camera.imageTopPosition + round(app.Camera.imageHeight/2);
            app.AreaOfInterest.xPosition = app.Camera.imageLeftPosition + round(app.Camera.imageWidth/2);
        end
        
        function MoveSensingRegionToMiddleOfAOI(app,name)
            if isequal(app.CameraName,'Zyla')
                region = app.SensingRegions.getRegion(name);
                region.xOffset = app.Camera.imageTopPosition
                region.yOffset = app.Camera.imageLeftPosition;
                region.xPosition = round(app.Camera.imageHeight/2);
                region.yPosition = round(app.Camera.imageWidth/2);
            else
                region = app.SensingRegions.getRegion(name);
                region.xOffset = app.Camera.imageLeftPosition;
                region.yOffset = app.Camera.imageTopPosition;
                region.xPosition = round(app.Camera.imageWidth/2);
                region.yPosition = round(app.Camera.imageHeight/2);
            end
        end
        
        function UpdateNamesInSelectRegionDropDown(app)
            app.SelectRegionDropDown.Items = app.SensingRegions.namesOfAllRegions;
            app.SelectRegionDropDown.Items{end+1} = 'Add region';
        end
        
        function UpdateCameraSettingsGui(app)
            
            if isequal(app.CameraName,'Zyla')
                
                app.TriggermodeDropDown.Items = app.Camera.ALLOWED_TRIGGER_MODES;
                app.TriggermodeDropDown.Value = app.Camera.triggerMode;
                
                app.ExposureTimeEditField.Value = app.Camera.exposureTime;
                
                app.UpdateFrameRateField();
                
                app.NumberOfAccumulationsEditField.Value = app.Camera.numberOfAccumulations;
                
                app.PixelReadoutRateDropDown.Items = app.Camera.ALLOWED_PIXEL_READ_OUT_RATES;
                app.PixelReadoutRateDropDown.Value = app.Camera.pixelReadoutRate;
                
                app.ElectronicShutteringModeDropDown.Items = app.Camera.ALLOWED_ELECTRONIC_SHUTTERING_MODES;
                app.ElectronicShutteringModeDropDown.Value = app.Camera.electronicShutteringMode;
                
                app.SensitivityDynamicRangeDropDown.Items = app.Camera.ALLOWED_SIMPLE_PRE_AMP_GAIN_CONTROLS;
                app.SensitivityDynamicRangeDropDown.Value = app.Camera.simplePreAmpGainControl;
                
                app.SpuriousNoiseFilterCheckBox.Value = app.Camera.spuriousNoiseFilter;
                
                app.OverlapReadoutCheckBox.Value = app.Camera.overlapReadout;
                
                app.AOIHeightSpinner.Value = app.Camera.imageWidth;
                app.AOIWidthSpinner.Value = app.Camera.imageHeight;
                app.AOIYpositionSpinner.Value = app.Camera.imageLeftPosition + round(app.Camera.imageWidth/2);
                app.AOIXpositionSpinner.Value = app.Camera.imageTopPosition + round(app.Camera.imageHeight/2);
                
                app.AreaOfInterest.height = app.Camera.imageWidth;
                app.AreaOfInterest.width = app.Camera.imageHeight;
                app.AreaOfInterest.yPosition = app.Camera.imageLeftPosition + round(app.Camera.imageWidth/2);
                app.AreaOfInterest.xPosition = app.Camera.imageTopPosition + round(app.Camera.imageHeight/2);
                
                app.VerticalBinningEditField.Value = app.Camera.horisontalBinning;
                app.HorisontalBinningEditField.Value = app.Camera.verticalBinning;
                
                
            elseif isequal(app.CameraName,'Newton')
                
                app.ExposureTimeNewtonEditField.Value = app.Camera.exposureTime;
                
                app.FramerateNewtonEditField.Value = app.Camera.frameRate;
                
                app.NumberofaccumulationsNewtonEditField.Value = app.Camera.numberOfAccumulations;
                
                app.SensortemperatureNewtonEditField.Value = app.Camera.sensorTemperature;
                
                app.CoolerNewtonDropDown.Items = app.Camera.ALLOWED_COOLER_OPTIONS;
                app.CoolerNewtonDropDown.Value = app.Camera.cooler;
                
                app.CoolermodeNewtonDropDown.Items = app.Camera.ALLOWED_COOLER_MODES;
                app.CoolermodeNewtonDropDown.Value = app.Camera.coolerMode;
                
                app.AOIHeightNewtonSpinner.Value = app.Camera.imageHeight;
                app.AOIWidthNewtonSpinner.Value = app.Camera.imageWidth;
                app.AOIYpositionNewtonSpinner.Value = app.Camera.imageTopPosition + round(app.Camera.imageHeight/2);
                app.AOIXpositionNewtonSpinner.Value = app.Camera.imageLeftPosition + round(app.Camera.imageWidth/2);
                
                app.AreaOfInterest.height = app.Camera.imageHeight;
                app.AreaOfInterest.width = app.Camera.imageWidth;
                app.AreaOfInterest.yPosition = app.Camera.imageTopPosition + round(app.Camera.imageHeight/2);
                app.AreaOfInterest.xPosition = app.Camera.imageLeftPosition + round(app.Camera.imageWidth/2);
                
                app.VerticalbinningNewtonEditField.Value = app.Camera.verticalBinning;
                app.HorisontalbinningNewtonEditField.Value = app.Camera.horisontalBinning;
                
            elseif isequal(app.CameraName,'iXon')
                
                app.ExposureTimeiXonEditField.Value = app.Camera.exposureTime;
                
                app.FramerateiXonEditField.Value = app.Camera.frameRate;
                
                app.NumberofaccumulationsiXonEditField.Value = app.Camera.numberOfAccumulations;
                
                app.SensortemperatureiXonEditField.Value = app.Camera.sensorTemperature;
                
                app.CoolerDropDown.Items = app.Camera.ALLOWED_COOLER_OPTIONS;
                app.CoolerDropDown.Value = app.Camera.cooler;
                
                app.CoolermodeiXonDropDown.Items = app.Camera.ALLOWED_COOLER_MODES;
                app.CoolermodeiXonDropDown.Value = app.Camera.coolerMode;
                
                app.EmgainmodeiXonDropDown.Items = app.Camera.ALLOWED_EM_GAIN_MODES;
                app.EmgainmodeiXonDropDown.Value = app.Camera.emGainMode;
                
                app.EmCCDgainiXonEditField.Value = app.Camera.emCCDGain;
                
                app.AOIHeightiXonSpinner.Value = app.Camera.imageHeight;
                app.AOIWidthiXonSpinner.Value = app.Camera.imageWidth;
                app.AOIYpositioniXonSpinner.Value = app.Camera.imageTopPosition + round(app.Camera.imageHeight/2);
                app.AOIXpositioniXonSpinner.Value = app.Camera.imageLeftPosition + round(app.Camera.imageWidth/2);
                
                app.AreaOfInterest.height = app.Camera.imageHeight;
                app.AreaOfInterest.width = app.Camera.imageWidth;
                app.AreaOfInterest.yPosition = app.Camera.imageTopPosition + round(app.Camera.imageHeight/2);
                app.AreaOfInterest.xPosition = app.Camera.imageLeftPosition + round(app.Camera.imageWidth/2);
                
                app.VerticalbinningiXonEditField.Value = app.Camera.verticalBinning;
                app.HorisontalbinningiXonEditField.Value = app.Camera.horisontalBinning;
                
            else
                
            end
            
            app.UpdateSettingsRegionPlot();
        end
        
        function InitialiseSingleAxisLiveAnalyzer(app)
            app.LiveAnalyzerSingleAxis.SensingRegions = app.SensingRegions;
            app.LiveAnalyzerSingleAxis.currentFrame = zeros(app.Camera.imageWidth,app.Camera.imageHeight);
            app.LiveAnalyzerSingleAxis.currentFrame = zeros(app.Camera.imageWidth,app.Camera.imageHeight);
            app.LiveAnalyzerSingleAxis.currentTime = 0;
            app.LiveAnalyzerSingleAxis.initializeSingleAxis(app.UIAxesSingle);
        end
        
        function InitialiseTripleAxisLiveAnalyzer(app)
            app.LiveAnalyzerTripleAxis.SensingRegions = app.SensingRegions;
            app.LiveAnalyzerTripleAxis.currentFrame = zeros(app.Camera.imageWidth,app.Camera.imageHeight);
            app.LiveAnalyzerTripleAxis.currentFrame = zeros(app.Camera.imageWidth,app.Camera.imageHeight);
            app.LiveAnalyzerTripleAxis.currentTime = 0;
            app.LiveAnalyzerTripleAxis.initializeTripleAxis(app.UIAxesTripleMain,app.UIAxesTripleLeft,app.UIAxesTripleBottom);
        end
        
        function AcquireNewSettingsRegionFrame(app)
            if ~app.Camera.isAcquiring
                app.Camera.startAcquisition();
            end
            app.SensingRegionSettingsFrame = app.Camera.getNextFrame();
        end
        
        function ResetZoomSensingRegionAxes(app)
            leftPosition = app.Camera.imageLeftPosition;
            topPosition = app.Camera.imageTopPosition;
            width = app.Camera.imageWidth;
            height = app.Camera.imageHeight;
            if isequal(app.CameraName,'Zyla')
                set(app.UIAxesSensingRegionsSettings,'ylim', [leftPosition,leftPosition+width]);
                set(app.UIAxesSensingRegionsSettings,'xlim', [topPosition,topPosition+height]);
                set(app.UIAxesSensingRegionsSettings,'zlimMode', 'auto');
            else
                set(app.UIAxesSensingRegionsSettings,'xlim', [leftPosition,leftPosition+width]);
                set(app.UIAxesSensingRegionsSettings,'ylim', [topPosition,topPosition+height]);
                set(app.UIAxesSensingRegionsSettings,'zlimMode', 'auto');
            end
            drawnow;
        end
        
        function UpdateFrameRateField(app)
            app.FramerateEditField.Value = app.Camera.frameRate;
            if app.Camera.frameRate > app.Camera.maxInterfaceTransferRate
                app.FramerateEditField.Value
                app.FramerateEditField.BackgroundColor = 'red';
            else
                app.FramerateEditField.BackgroundColor = 'white';
            end
        end
        
        
        function UpdateLivePlotsAndPlotRate(app,frame,time)
            app.LiveAnalyzerSingleAxis.currentFrame = frame;
            app.LiveAnalyzerSingleAxis.currentTime = time;
            
            app.LiveAnalyzerTripleAxis.currentFrame = frame;
            app.LiveAnalyzerTripleAxis.currentTime = time;
            
            app.LiveAnalyzerSingleAxis.plotOnSingleAxis(app.UIAxesSingle);
            app.LiveAnalyzerTripleAxis.plotOnTripleAxis(app.UIAxesTripleMain,app.UIAxesTripleLeft,app.UIAxesTripleBottom);
            drawnow
            
            currentTime = toc(app.StartTimeOfAcquisition);
            app.PlotRateEditField.Value = 1/(currentTime-app.TimeOfLastPlotting);
            app.TimeOfLastPlotting = currentTime;
        end
        
        function StopRecording(app)
            app.IsRecording = false;
            app.FileWriter.closeFile();
            app.nRecordedFrames =  0;
            app.RecordedFramesEditField.Value = 0;
            app.RecordingTimeEditField.Value = 0;
        end
        
        function StartRecording(app)
            app.IsRecording = true;
            app.nRecordedFrames = 0;
        end
        
        function InitialiseFileWriter(app)
            path = uigetdir;
            app.FileWriter = FileWriter([path,'/'],app.SensingRegions,app.Camera.cameraInfo);
        end
        
        function StartAcquiringFrames(app)
            app.ShouldBeAcquiringFrames = true;
            app.Camera.startAcquisition();
            maxAllowedLatency = app.CalculateMaxAllowedLatency();
            
            app.StartTimeOfAcquisition = tic;
            app.nAcquiredFrames = 0;
            
            while app.ShouldBeAcquiringFrames
                
                [frame, time] = app.Camera.getNextFrame();
                app.nAcquiredFrames = app.nAcquiredFrames + 1;


                if isequal(app.CameraName,'Newton')
                    progressedTime = toc(app.StartTimeOfAcquisition);
                    if progressedTime < app.nAcquiredFrames/app.Camera.frameRate + 1.5/app.Camera.frameRate
                        app.UpdateLivePlotsAndPlotRate(frame,time);
                        app.UpdateCameraTemperature();
                    end
                else
                    latency = app.CalculateLatency(time);
                    app.UpdateLatency(latency);
                    
                    if latency < maxAllowedLatency
                        app.UpdateLivePlotsAndPlotRate(frame,time);
                        app.UpdateCameraTemperature();
                    end
                end
                
                if app.IsRecording
                    app.FileWriter.writeFrameToFiles(frame,time);
                    app.UpdateRecordingStatuses(time);
                end
                pause(0.00001);
            end
            app.Camera.stopAcquisition();
        end
        
        function allowedLatency = CalculateMaxAllowedLatency(app)
            allowedLatency = app.Camera.exposureTime * app.Camera.numberOfAccumulations + 1;
        end
        
        function UpdateCameraTemperature(app)
            app.TemperatureEditField.Value = app.Camera.sensorTemperature;
        end
        
        function latency = CalculateLatency(app,time)
            currentTime = toc(app.StartTimeOfAcquisition);
            latency = currentTime - time;
        end
        
        function UpdateLatency(app,latency)
            app.LatencysEditField.Value = latency;
        end
        
        function UpdateRecordingStatuses(app,time)
            app.nRecordedFrames =  app.nRecordedFrames + 1;
            app.RecordedFramesEditField.Value = app.nRecordedFrames;
            app.RecordingTimeEditField.Value = time;
        end
        
        function StopAcquiringFrames(app)
            app.ShouldBeAcquiringFrames = false;
        end
        
        function RemoveSettingsTabOfNonSelectedCameras(app)
            indexes = []
            for n=1:length(app.SpecificCameraSettings.Children)
                if ~isequal(app.SpecificCameraSettings.Children(n),app.SpecificCameraSettings.SelectedTab)
                    indexes(end+1) = n;
                end
            end
            delete(app.SpecificCameraSettings.Children(indexes));
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('source/SensingRegions/');
            addpath('source/LiveAnalyzer/');
            addpath('source/Cameras/');
            addpath('source/FileWriter/');
            addpath('source/UserCustomisation/');
            addpath('source/DefaultLiveAnalyzers/');
            addpath('source/PerformanceTester/');
            clear lightbox; close all; clc;
            cameraNames = {'Zyla', 'iXon', 'Newton', 'Simulator'};
            [index,~] = listdlg('ListString',cameraNames,'SelectionMode','single');
            app.CameraName = cameraNames{index};
            if isequal('Zyla',app.CameraName)
                app.Camera = ZylaCamera();
                app.SpecificCameraSettings.SelectedTab = app.SpecificCameraSettings.Children(1);
            elseif isequal('iXon',app.CameraName)
                app.Camera = iXonCamera();
                app.SpecificCameraSettings.SelectedTab = app.SpecificCameraSettings.Children(2);
            elseif isequal('Newton',app.CameraName)
                app.Camera = NewtonCamera();
                app.SpecificCameraSettings.SelectedTab = app.SpecificCameraSettings.Children(4);
            else
                app.Camera = CameraSimulator();
                app.SpecificCameraSettings.SelectedTab = app.SpecificCameraSettings.Children(3);
            end
            app.RemoveSettingsTabOfNonSelectedCameras()
            app.SensingRegions = SensingRegions();
            app.UserCustomisation = UserCustomisation();
            app.IsRecording = false;
            app.InitialiseAreaOfInterest();
            app.PlotFunctionSingleAxisDropDown.Items = app.UserCustomisation.namesOfSingleAxisLiveAnalyzers;
            app.PlotFunctionTripleAxisDropDown.Items = app.UserCustomisation.namesOfTripleAxisLiveAnalyzers;
            app.LiveAnalyzerSingleAxis = app.UserCustomisation.getSingleAxisLiveAnalyzerByName(app.PlotFunctionSingleAxisDropDown.Value);
            app.LiveAnalyzerTripleAxis = app.UserCustomisation.getTripleAxisLiveAnalyzerByName(app.PlotFunctionTripleAxisDropDown.Value);
            app.PerformanceTester = PerformanceTester();
        end

        % Value changed function: SelectRegionDropDown
        function SelectRegionDropDownValueChanged(app, event)
            if isequal(event.Value,'Add region')
                name = app.SensingRegions.addNewRegion();
                app.MoveSensingRegionToMiddleOfAOI(name);
                app.addNameToSelectRegionDropDown(name);
            elseif ~app.SensingRegions.regionNameExists(event.Value)
                app.SensingRegions.editRegionName(event.PreviousValue,event.Value);
                app.UpdateNamesInSelectRegionDropDown();
            end
            app.UpdateRegionSettingsGui();
        end

        % Button pushed function: DeleteRegionButton
        function DeleteRegionButtonPushed(app, event)
            app.SensingRegions.removeRegion(app.SelectRegionDropDown.Value);
            if app.SensingRegions.nRegions > 0
                app.SelectRegionDropDown.Items = app.SensingRegions.namesOfAllRegions;
                app.SelectRegionDropDown.Items{end+1} = 'Add region';
                app.SelectRegionDropDown.Value = app.SensingRegions.namesOfAllRegions{1};
            else
                app.SelectRegionDropDown.Items = {'','Add region'};
                app.SelectRegionDropDown.Value = '';
            end
            app.UpdateRegionSettingsGui();
        end

        % Button pushed function: RefreshSensingRegionButton
        function RefreshSensingRegionAxesButtonPushed(app, event)
            app.AcquireNewSettingsRegionFrame();
            app.UpdateSettingsRegionPlot();
        end

        % Button pushed function: ResetZoomSensingRegionAxesButton
        function ResetZoomSensingRegionAxesButtonPushed(app, event)
            app.ResetZoomSensingRegionAxes();
        end

        % Value changed function: WidthSpinner
        function WidthSpinnerValueChanged(app, event)
            regionName = app.SelectRegionDropDown.Value;
            if ~isequal(regionName,'')
                region = app.SensingRegions.getRegion(regionName);
                region.width = event.Value;
                app.UpdateSettingsRegionPlot();
            end
        end

        % Value changed function: HeightSpinner
        function HeightSpinnerValueChanged(app, event)
            regionName = app.SelectRegionDropDown.Value;
            if ~isequal(regionName,'')
                region = app.SensingRegions.getRegion(regionName);
                region.height = event.Value;
                app.UpdateSettingsRegionPlot();
            end
        end

        % Value changed function: XpositionSpinner
        function XpositionSpinnerValueChanged(app, event)
            regionName = app.SelectRegionDropDown.Value;
            if ~isequal(regionName,'')
                region = app.SensingRegions.getRegion(regionName);
                region.xPosition = event.Value - region.xOffset;
                app.UpdateSettingsRegionPlot();
            end
        end

        % Value changed function: YpositionSpinner
        function YpositionSpinnerValueChanged(app, event)
            regionName = app.SelectRegionDropDown.Value;
            if ~isequal(regionName,'')
                region = app.SensingRegions.getRegion(regionName);
                region.yPosition = event.Value - region.yOffset;
                app.UpdateSettingsRegionPlot();
            end
        end

        % Value changed function: IsReferenceRegionCheckBox
        function IsReferenceRegionCheckBoxValueChanged(app, event)
            if event.Value
                app.SensingRegions.setRegionType(app.SelectRegionDropDown.Value,'reference');
                app.IsBackgroundRegionCheckBox.Value = false;
                app.disableAndEmptyReferenceListBoxes();
            else
                app.SensingRegions.setRegionType(app.SelectRegionDropDown.Value,'signal');
                app.enableReferenceListBoxes();
            end
            app.UpdateRegionSettingsGui();
        end

        % Value changed function: BackgroundRegionsListBox
        function BackgroundRegionsListBoxValueChanged(app, event)
            app.UpdateRegionAssociations();
            app.UpdateRegionSettingsGui();
        end

        % Value changed function: ReferenceRegionsListBox
        function ReferenceRegionsListBoxValueChanged(app, event)
            app.UpdateRegionAssociations();
            app.UpdateRegionSettingsGui();
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if isequal(selectedTab.Title,'Settings')
                app.StopAcquiringFrames();
                app.PlayButton.Value = false;
                app.Camera.startAcquisition();
                app.AcquireNewSettingsRegionFrame();
                app.UpdateSettingsRegionPlot();
                app.ResetZoomSensingRegionAxes();
                app.UpdateCameraSettingsGui();
                if isequal(app.CameraName,'Zyla')
                    app.PresetsListBox.Items = [{'None'},app.UserCustomisation.namesOfPreSets];
                    app.PresetsListBox.Value = 'None';
                end
            else
                app.Camera.stopAcquisition();
            end
        end

        % Button pushed function: ResetZoomTripleAxisButton
        function ResetZoomTripleAxisButtonPushed(app, event)
            app.LiveAnalyzerTripleAxis.resetZoomTripleAxis(app.UIAxesTripleMain,app.UIAxesTripleLeft,app.UIAxesTripleBottom);
            drawnow;
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            if app.IsRecording
                app.StartRecordingButton.Text = 'Start recording';
                app.StopRecording();
            else
                app.StopAcquiringFrames();
                app.InitialiseFileWriter();
                app.StartRecordingButton.Text = 'Stop recording';
                app.StartRecording();
                app.StartAcquiringFrames();
            end
        end

        % Value changed function: IsBackgroundRegionCheckBox
        function IsBackgroundRegionCheckBoxValueChanged(app, event)
            if event.Value
                app.SensingRegions.setRegionType(app.SelectRegionDropDown.Value,'background');
                app.IsReferenceRegionCheckBox.Value = false;
                app.disableAndEmptyReferenceListBoxes();
            else
                app.SensingRegions.setRegionType(app.SelectRegionDropDown.Value,'signal');
                app.enableReferenceListBoxes();
            end
            app.UpdateRegionSettingsGui();
        end

        % Value changed function: ExposureTimeEditField
        function ExposureTimeEditFieldValueChanged(app, event)
            app.Camera.exposureTime = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: AOIXpositionSpinner
        function AOIXpositionSpinnerValueChanged(app, event)
            app.AreaOfInterest.xPosition = event.Value;
            app.UpdateSettingsRegionPlot();
        end

        % Value changed function: AOIYpositionSpinner
        function AOIYpositionSpinnerValueChanged(app, event)
            app.AreaOfInterest.yPosition = event.Value;
            app.UpdateSettingsRegionPlot();
        end

        % Value changed function: AOIWidthSpinner
        function AOIWidthSpinnerValueChanged(app, event)
            app.AreaOfInterest.width = event.Value;
            app.UpdateSettingsRegionPlot();
        end

        % Value changed function: AOIHeightSpinner
        function AOIHeightSpinnerValueChanged(app, event)
            app.AreaOfInterest.height = event.Value;
            app.UpdateSettingsRegionPlot();
        end

        % Button pushed function: ApplyAOIButton
        function ApplyAOIButtonPushed(app, event)
            coordinates = app.AreaOfInterest.coordinates;
            app.Camera.setAreaOfInterest(app.AreaOfInterest.height,app.AreaOfInterest.width,coordinates(1,2),coordinates(1,1));
            app.UpdateCameraSettingsGui();
            app.UpdateRegionSettingsGui();
            app.AcquireNewSettingsRegionFrame();
            app.UpdateSettingsRegionPlot();
            app.ResetZoomSensingRegionAxes();
        end

        % Button pushed function: ResetAOIButton
        function ResetAOIButtonPushed(app, event)
            app.Camera.setMaxAreaOfInterest();
            app.UpdateCameraSettingsGui();
            app.UpdateRegionSettingsGui();
            app.AcquireNewSettingsRegionFrame();
            app.UpdateSettingsRegionPlot();
            app.ResetZoomSensingRegionAxes();
        end

        % Value changed function: NumberOfAccumulationsEditField
        function NumberOfAccumulationsEditFieldValueChanged(app, event)
            app.Camera.numberOfAccumulations = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: VerticalBinningEditField
        function VerticalBinningEditFieldValueChanged(app, event)
            app.Camera.horisontalBinning = event.Value;
            app.UpdateCameraSettingsGui();
            app.AcquireNewSettingsRegionFrame();
            app.UpdateSettingsRegionPlot();
            app.ResetZoomSensingRegionAxes();
        end

        % Value changed function: HorisontalBinningEditField
        function HorisontalBinningEditFieldValueChanged(app, event)
            app.Camera.verticalBinning = event.Value;
            app.UpdateCameraSettingsGui();
            app.AcquireNewSettingsRegionFrame();
            app.UpdateSettingsRegionPlot();
            app.ResetZoomSensingRegionAxes();
        end

        % Value changed function: PlotFunctionTripleAxisDropDown
        function PlotFunctionTripleAxisDropDownValueChanged(app, event)
            app.LiveAnalyzerTripleAxis = app.UserCustomisation.getTripleAxisLiveAnalyzerByName(event.Value);
            app.InitialiseTripleAxisLiveAnalyzer();
        end

        % Value changed function: PlotFunctionSingleAxisDropDown
        function PlotFunctionSingleAxisDropDownValueChanged(app, event)
            app.LiveAnalyzerSingleAxis = app.UserCustomisation.getSingleAxisLiveAnalyzerByName(event.Value);
            app.InitialiseSingleAxisLiveAnalyzer();
        end

        % Close request function: FrameAnalysisinRealTimeRTFAUIFigure
        function FrameAnalysisinRealTimeRTFAUIFigureCloseRequest(app, event)
            try
                app.FileWriter.closeAllFiles();
                app.Camera.close();
            catch e
                disp(e);
            end
            delete(app)
        end

        % Button pushed function: ResetZoomSingleAxisButton
        function ResetZoomSingleAxisButtonPushed(app, event)
            app.LiveAnalyzerSingleAxis.resetZoomSingleAxis(app.UIAxesSingle);
            drawnow;
        end

        % Callback function
        function FanSwitchValueChanged(app, event)
            value = app.FanSwitch.Value;
            if isequal(event.Value,'On')
                app.Camera.fanSpeed = 'on';
            else
                app.Camera.fanSpeed = 'off';
            end
        end

        % Callback function
        function ImportSettingsFileButtonPushed(app, event)
            %[file,path] = uigetfile;
        end

        % Value changed function: ElectronicShutteringModeDropDown
        function ElectronicShutteringModeDropDownValueChanged(app, event)
            app.Camera.electronicShutteringMode = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: PixelReadoutRateDropDown
        function PixelReadoutRateDropDownValueChanged(app, event)
            app.Camera.pixelReadoutRate = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: SensitivityDynamicRangeDropDown
        function SensitivityDynamicRangeDropDownValueChanged(app, event)
            app.Camera.simplePreAmpGainControl = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: SpuriousNoiseFilterCheckBox
        function SpuriousNoiseFilterCheckBoxValueChanged(app, event)
            app.Camera.spuriousNoiseFilter = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: OverlapReadoutCheckBox
        function OverlapReadoutCheckBoxValueChanged(app, event)
            app.Camera.overlapReadout = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Button pushed function: SnapShotSingleAxisFigureButton
        function SnapShotSingleAxisFigureButtonPushed(app, event)
            app.LiveAnalyzerSingleAxis.openNewFigureWithCurrentPlot();
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            if event.Value
                app.StartAcquiringFrames();
            else
                app.StopAcquiringFrames();
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.PlayButton.Value = 0;
            app.StopAcquiringFrames();
        end

        % Button pushed function: TestwritespeedButton
        function TestwritespeedButtonPushed(app, event)
            app.PerformanceLamp.Color = [0.65,0.65,0.65];
            pause(0.001);
            time = app.PerformanceTester.timeWriteOneFrameToDisk(app.SensingRegionSettingsFrame)
            if time < app.Camera.exposureTime*app.Camera.numberOfAccumulations;
                app.PerformanceLamp.Color = [0,1,0];
            else
                app.PerformanceLamp.Color = [1,0,0];
            end
        end

        % Selection change function: SpecificCameraSettings
        function SpecificCameraSettingsSelectionChanged(app, event)
            selectedTab = app.SpecificCameraSettings.SelectedTab;
            
        end

        % Value changed function: AOIXpositioniXonSpinner
        function AOIXpositioniXonSpinnerValueChanged(app, event)
            app.AreaOfInterest.xPosition = event.Value;
            app.UpdateSettingsRegionPlot();
        end

        % Value changed function: AOIYpositioniXonSpinner
        function AOIYpositioniXonSpinnerValueChanged(app, event)
            app.AreaOfInterest.yPosition = event.Value;
            app.UpdateSettingsRegionPlot();
        end

        % Value changed function: AOIWidthiXonSpinner
        function AOIWidthiXonSpinnerValueChanged(app, event)
            app.AreaOfInterest.width = event.Value;
            app.UpdateSettingsRegionPlot();
        end

        % Value changed function: AOIHeightiXonSpinner
        function AOIHeightiXonSpinnerValueChanged(app, event)
            app.AreaOfInterest.height = event.Value;
            app.UpdateSettingsRegionPlot();
        end

        % Button pushed function: ApplyAOIiXonButton
        function ApplyAOIiXonButtonPushed(app, event)
            coordinates = app.AreaOfInterest.coordinates;
            horisontalBinning = app.HorisontalbinningiXonEditField.Value;
            verticalBinning = app.VerticalbinningiXonEditField.Value;
            app.Camera.setAreaOfInterest(verticalBinning,horisontalBinning,app.AreaOfInterest.width,app.AreaOfInterest.height,coordinates(1,1),coordinates(1,2));
            app.UpdateCameraSettingsGui();
            app.UpdateRegionSettingsGui();
            app.AcquireNewSettingsRegionFrame();
            app.UpdateSettingsRegionPlot();
            app.ResetZoomSensingRegionAxes();
        end

        % Button pushed function: ResetAOIiXonButton
        function ResetAOIiXonButtonPushed(app, event)
            app.Camera.resetAreaOfInterest();
            app.UpdateCameraSettingsGui();
            app.UpdateRegionSettingsGui();
            app.AcquireNewSettingsRegionFrame();
            app.UpdateSettingsRegionPlot();
            app.ResetZoomSensingRegionAxes();
        end

        % Value changed function: ExposureTimeiXonEditField
        function ExposureTimeiXonEditFieldValueChanged(app, event)
            app.Camera.exposureTime = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: CoolermodeiXonDropDown
        function CoolermodeiXonDropDownValueChanged(app, event)
            app.Camera.coolerMode = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: SensortemperatureiXonEditField
        function SensortemperatureiXonEditFieldValueChanged(app, event)
            app.Camera.sensorTemperature = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: EmgainmodeiXonDropDown
        function EmgainmodeiXonDropDownValueChanged(app, event)
            app.Camera.emGainMode = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: EmCCDgainiXonEditField
        function EmCCDgainiXonEditFieldValueChanged(app, event)
            app.Camera.emCCDGain = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: NumberofaccumulationsiXonEditField
        function NumberofaccumulationsiXonEditFieldValueChanged(app, event)
            app.Camera.numberOfAccumulations = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Button pushed function: TestwritespeediXonButton
        function TestwritespeediXonButtonPushed(app, event)
            app.TestWritespeediXonLamp.Color = [0.65,0.65,0.65];
            pause(0.001);
            time = app.PerformanceTester.timeWriteOneFrameToDisk(app.SensingRegionSettingsFrame)
            if time < 1/app.Camera.frameRate*0.8;
                app.TestWritespeediXonLamp.Color = [0,1,0];
            else
                app.TestWritespeediXonLamp.Color = [1,0,0];
            end
        end

        % Value changed function: TriggermodeDropDown
        function TriggermodeDropDownValueChanged(app, event)
            app.Camera.triggerMode = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: PresetsListBox
        function PresetsListBoxValueChanged(app, event)
            presetName = app.PresetsListBox.Value;
            if ~isequal(presetName,'None')
                preset = app.UserCustomisation.getPreSetByName(presetName);
                app.Camera.usePresets(preset);
                app.UpdateCameraSettingsGui();
                app.AcquireNewSettingsRegionFrame();
                app.UpdateRegionSettingsGui();
                app.UpdateSettingsRegionPlot();
                app.ResetZoomSensingRegionAxes();
            end
        end

        % Button pushed function: ResetAOINewtonButton
        function ResetAOINewtonButtonPushed(app, event)
            app.Camera.resetAreaOfInterest();
            app.UpdateCameraSettingsGui();
            app.UpdateRegionSettingsGui();
            app.AcquireNewSettingsRegionFrame();
            app.UpdateSettingsRegionPlot();
            app.ResetZoomSensingRegionAxes();
        end

        % Button pushed function: ApplyAOINewtonButton
        function ApplyAOINewtonButtonPushed(app, event)
            coordinates = app.AreaOfInterest.coordinates;
            horisontalBinning = app.HorisontalbinningNewtonEditField.Value;
            verticalBinning = app.VerticalbinningNewtonEditField.Value;
            app.Camera.setAreaOfInterest(verticalBinning,horisontalBinning,app.AreaOfInterest.width,app.AreaOfInterest.height,coordinates(1,1),coordinates(1,2));
            app.UpdateCameraSettingsGui();
            app.UpdateRegionSettingsGui();
            app.AcquireNewSettingsRegionFrame();
            app.UpdateSettingsRegionPlot();
            app.ResetZoomSensingRegionAxes();
        end

        % Button pushed function: TestwritespeedNewtonButton
        function TestwritespeedNewtonButtonPushed(app, event)
            app.TestWritespeedNewtonLamp.Color = [0.65,0.65,0.65];
            pause(0.001);
            time = app.PerformanceTester.timeWriteOneFrameToDisk(app.SensingRegionSettingsFrame)
            if time < 1/app.Camera.frameRate*0.8;
                app.TestWritespeedNewtonLamp.Color = [0,1,0];
            else
                app.TestWritespeedNewtonLamp.Color = [1,0,0];
            end
        end

        % Value changed function: CoolerNewtonDropDown
        function CoolerNewtonDropDownValueChanged(app, event)
            app.Camera.cooler = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: CoolerDropDown
        function CoolerDropDownValueChanged(app, event)
            app.Camera.cooler = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: ExposureTimeNewtonEditField
        function ExposureTimeNewtonEditFieldValueChanged(app, event)
            app.Camera.exposureTime = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: CoolermodeNewtonDropDown
        function CoolermodeNewtonDropDownValueChanged(app, event)
            app.Camera.coolerMode = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: SensortemperatureNewtonEditField
        function SensortemperatureNewtonEditFieldValueChanged(app, event)
            app.Camera.sensorTemperature = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: NumberofaccumulationsNewtonEditField
        function NumberofaccumulationsNewtonEditFieldValueChanged(app, event)
            app.Camera.numberOfAccumulations = event.Value;
            app.UpdateCameraSettingsGui();
        end

        % Value changed function: AOIXpositionNewtonSpinner
        function AOIXpositionNewtonSpinnerValueChanged(app, event)
            app.AreaOfInterest.xPosition = event.Value;
            app.UpdateSettingsRegionPlot();
        end

        % Value changed function: AOIYpositionNewtonSpinner
        function AOIYpositionNewtonSpinnerValueChanged(app, event)
            app.AreaOfInterest.yPosition = event.Value;
            app.UpdateSettingsRegionPlot();
        end

        % Value changed function: AOIWidthNewtonSpinner
        function AOIWidthNewtonSpinnerValueChanged(app, event)
            app.AreaOfInterest.width = event.Value;
            app.UpdateSettingsRegionPlot();
        end

        % Value changed function: AOIHeightNewtonSpinner
        function AOIHeightNewtonSpinnerValueChanged(app, event)
            app.AreaOfInterest.height = event.Value;
            app.UpdateSettingsRegionPlot();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FrameAnalysisinRealTimeRTFAUIFigure = uifigure;
            app.FrameAnalysisinRealTimeRTFAUIFigure.Name = 'Real Time Frame Analysis';
            app.FrameAnalysisinRealTimeRTFAUIFigure.CloseRequestFcn = createCallbackFcn(app, @FrameAnalysisinRealTimeRTFAUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.FrameAnalysisinRealTimeRTFAUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Main = uitab(app.TabGroup);
            app.Main.Title = 'Main';
            app.TripleAxisPlotterPanel = uipanel(app.Main);
            app.TripleAxisPlotterPanel.Title = 'Triple Axis Plotter';
            app.UIAxesTripleMain = uiaxes(app.TripleAxisPlotterPanel);
            title(app.UIAxesTripleMain, '')
            app.UIAxesTripleMain.XTick = [];
            app.UIAxesTripleMain.YTick = [];
            app.UIAxesTripleLeft = uiaxes(app.TripleAxisPlotterPanel);
            app.UIAxesTripleLeft.YTick = [];
            app.PlotfunctionDropDownLabel = uilabel(app.TripleAxisPlotterPanel);
            app.PlotfunctionDropDownLabel.Text = 'Plot function';
            app.PlotFunctionTripleAxisDropDown = uidropdown(app.TripleAxisPlotterPanel);
            app.PlotFunctionTripleAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotFunctionTripleAxisDropDownValueChanged, true);
            app.ResetZoomTripleAxisButton = uibutton(app.TripleAxisPlotterPanel, 'push');
            app.ResetZoomTripleAxisButton.ButtonPushedFcn = createCallbackFcn(app, @ResetZoomTripleAxisButtonPushed, true);
            app.ResetZoomTripleAxisButton.Icon = 'glyphicons-halflings-10-home@2x.png';
            app.ResetZoomTripleAxisButton.Text = '';
            app.UIAxesTripleBottom = uiaxes(app.TripleAxisPlotterPanel);
            app.UIAxesTripleBottom.XTick = [];
            app.SingleAxisPlotterPanel = uipanel(app.Main);
            app.SingleAxisPlotterPanel.Title = 'Single Axis Plotter';
            app.UIAxesSingle = uiaxes(app.SingleAxisPlotterPanel);
            title(app.UIAxesSingle, '')
            xlabel(app.UIAxesSingle, 'X')
            ylabel(app.UIAxesSingle, 'Y')
            app.UIAxesSingle.XTick = [];
            app.UIAxesSingle.YTick = [];
            app.PlotfunctionDropDown_2Label = uilabel(app.SingleAxisPlotterPanel);
            app.PlotfunctionDropDown_2Label.Text = 'Plot function';
            app.PlotFunctionSingleAxisDropDown = uidropdown(app.SingleAxisPlotterPanel);
            app.PlotFunctionSingleAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotFunctionSingleAxisDropDownValueChanged, true);
            app.ResetZoomSingleAxisButton = uibutton(app.SingleAxisPlotterPanel, 'push');
            app.ResetZoomSingleAxisButton.ButtonPushedFcn = createCallbackFcn(app, @ResetZoomSingleAxisButtonPushed, true);
            app.ResetZoomSingleAxisButton.Icon = 'glyphicons-halflings-10-home@2x.png';
            app.ResetZoomSingleAxisButton.Text = '';
            app.SnapShotSingleAxisFigureButton = uibutton(app.SingleAxisPlotterPanel, 'push');
            app.SnapShotSingleAxisFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SnapShotSingleAxisFigureButtonPushed, true);
            app.SnapShotSingleAxisFigureButton.Icon = 'glyphicons-halflings-4-camera@2x.png';
            app.SnapShotSingleAxisFigureButton.Text = '';
            app.ControlPanel = uipanel(app.Main);
            app.PauseButton = uibutton(app.ControlPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = 'glyphicons-basic-174-pause@2x.png';
            app.PauseButton.Text = '';
            app.StartRecordingButton = uibutton(app.ControlPanel, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start recording';
            app.PlayButton = uibutton(app.ControlPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Icon = 'glyphicons-basic-175-play@2x.png';
            app.PlayButton.Text = '';
            app.StatusSettingsPanel = uipanel(app.Main);
            app.StatusSettingsPanel.Title = 'Status & Settings';
            app.PlotrateLabel = uilabel(app.StatusSettingsPanel);
            app.PlotrateLabel.Text = 'Plot rate';
            app.PlotRateEditField = uieditfield(app.StatusSettingsPanel, 'numeric');
            app.PlotRateEditField.Editable = 'off';
            app.TemperatureEditFieldLabel = uilabel(app.StatusSettingsPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.StatusSettingsPanel, 'numeric');
            app.TemperatureEditField.Editable = 'off';
            app.RecordingtimeEditFieldLabel = uilabel(app.StatusSettingsPanel);
            app.RecordingtimeEditFieldLabel.Text = 'Recording time';
            app.RecordingTimeEditField = uieditfield(app.StatusSettingsPanel, 'numeric');
            app.RecordingTimeEditField.Editable = 'off';
            app.RecordedFramesEditFieldLabel = uilabel(app.StatusSettingsPanel);
            app.RecordedFramesEditFieldLabel.Text = 'Recorded Frames';
            app.RecordedFramesEditField = uieditfield(app.StatusSettingsPanel, 'numeric');
            app.RecordedFramesEditField.Editable = 'off';
            app.LatencysEditFieldLabel = uilabel(app.StatusSettingsPanel);
            app.LatencysEditFieldLabel.Text = 'Latency [s]';
            app.LatencysEditField = uieditfield(app.StatusSettingsPanel, 'numeric');
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SpecificCameraSettings = uitabgroup(app.SettingsTab);
            app.SpecificCameraSettings.SelectionChangedFcn = createCallbackFcn(app, @SpecificCameraSettingsSelectionChanged, true);
            app.ZylaSettings = uitab(app.SpecificCameraSettings);
            app.ZylasettingsPanel = uipanel(app.ZylaSettings);
            app.ZylasettingsPanel.Title = 'Zyla settings';
            app.SpuriousNoiseFilterCheckBox = uicheckbox(app.ZylasettingsPanel);
            app.SpuriousNoiseFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpuriousNoiseFilterCheckBoxValueChanged, true);
            app.SpuriousNoiseFilterCheckBox.Text = 'Spurious noise filter';
            app.OverlapReadoutCheckBox = uicheckbox(app.ZylasettingsPanel);
            app.OverlapReadoutCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlapReadoutCheckBoxValueChanged, true);
            app.OverlapReadoutCheckBox.Text = 'Overlap readout';
            app.AOILabel = uilabel(app.ZylasettingsPanel);
            app.AOILabel.Text = 'Area of interest:';
            app.ApplyAOIButton = uibutton(app.ZylasettingsPanel, 'push');
            app.ApplyAOIButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAOIButtonPushed, true);
            app.ApplyAOIButton.Text = 'Apply';
            app.ResetAOIButton = uibutton(app.ZylasettingsPanel, 'push');
            app.ResetAOIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAOIButtonPushed, true);
            app.ResetAOIButton.Text = 'Reset';
            app.PerformanceLamp = uilamp(app.ZylasettingsPanel);
            app.ExposuretimeEditFieldLabel = uilabel(app.ZylasettingsPanel);
            app.ExposuretimeEditFieldLabel.Text = 'Exposure time';
            app.ExposureTimeEditField = uieditfield(app.ZylasettingsPanel, 'numeric');
            app.ExposureTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeEditFieldValueChanged, true);
            app.ExposureTimeEditField.Value = 0.01;
            app.XpositionSpinner_2Label = uilabel(app.ZylasettingsPanel);
            app.XpositionSpinner_2Label.Text = 'X position';
            app.AOIXpositionSpinner = uispinner(app.ZylasettingsPanel);
            app.AOIXpositionSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIXpositionSpinnerValueChanged, true);
            app.WidthSpinner_2Label = uilabel(app.ZylasettingsPanel);
            app.WidthSpinner_2Label.Text = 'Width';
            app.AOIWidthSpinner = uispinner(app.ZylasettingsPanel);
            app.AOIWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIWidthSpinnerValueChanged, true);
            app.YpositionSpinner_3Label = uilabel(app.ZylasettingsPanel);
            app.YpositionSpinner_3Label.Text = 'Y position';
            app.AOIYpositionSpinner = uispinner(app.ZylasettingsPanel);
            app.AOIYpositionSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIYpositionSpinnerValueChanged, true);
            app.HeightSpinner_2Label = uilabel(app.ZylasettingsPanel);
            app.HeightSpinner_2Label.Text = 'Height';
            app.AOIHeightSpinner = uispinner(app.ZylasettingsPanel);
            app.AOIHeightSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIHeightSpinnerValueChanged, true);
            app.FramerateEditFieldLabel = uilabel(app.ZylasettingsPanel);
            app.FramerateEditFieldLabel.Text = 'Frame rate';
            app.FramerateEditField = uieditfield(app.ZylasettingsPanel, 'numeric');
            app.FramerateEditField.Editable = 'off';
            app.NumberofaccumulationsEditFieldLabel = uilabel(app.ZylasettingsPanel);
            app.NumberofaccumulationsEditFieldLabel.Text = 'Number of accumulations';
            app.NumberOfAccumulationsEditField = uieditfield(app.ZylasettingsPanel, 'numeric');
            app.NumberOfAccumulationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfAccumulationsEditFieldValueChanged, true);
            app.PixelreadoutrateDropDownLabel = uilabel(app.ZylasettingsPanel);
            app.PixelreadoutrateDropDownLabel.Text = 'Pixel read out rate';
            app.PixelReadoutRateDropDown = uidropdown(app.ZylasettingsPanel);
            app.PixelReadoutRateDropDown.Items = {'100 MHz', '280 MHz'};
            app.PixelReadoutRateDropDown.ValueChangedFcn = createCallbackFcn(app, @PixelReadoutRateDropDownValueChanged, true);
            app.PixelReadoutRateDropDown.Value = '100 MHz';
            app.ElectronicshutteringmodeDropDownLabel = uilabel(app.ZylasettingsPanel);
            app.ElectronicshutteringmodeDropDownLabel.Text = 'Electronic shuttering mode';
            app.ElectronicShutteringModeDropDown = uidropdown(app.ZylasettingsPanel);
            app.ElectronicShutteringModeDropDown.Items = {'rolling', 'global'};
            app.ElectronicShutteringModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ElectronicShutteringModeDropDownValueChanged, true);
            app.ElectronicShutteringModeDropDown.Value = 'rolling';
            app.SensitivityDynamicrangeDropDownLabel = uilabel(app.ZylasettingsPanel);
            app.SensitivityDynamicrangeDropDownLabel.Text = 'Sensitivity/Dynamic range';
            app.SensitivityDynamicRangeDropDown = uidropdown(app.ZylasettingsPanel);
            app.SensitivityDynamicRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @SensitivityDynamicRangeDropDownValueChanged, true);
            app.VerticalbinningEditFieldLabel = uilabel(app.ZylasettingsPanel);
            app.VerticalbinningEditFieldLabel.Text = 'Vertical binning';
            app.VerticalBinningEditField = uieditfield(app.ZylasettingsPanel, 'numeric');
            app.VerticalBinningEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalBinningEditFieldValueChanged, true);
            app.HorisontalbinningEditFieldLabel = uilabel(app.ZylasettingsPanel);
            app.HorisontalbinningEditFieldLabel.Text = 'Horisontal binning';
            app.HorisontalBinningEditField = uieditfield(app.ZylasettingsPanel, 'numeric');
            app.HorisontalBinningEditField.ValueChangedFcn = createCallbackFcn(app, @HorisontalBinningEditFieldValueChanged, true);
            app.TestwritespeedButton = uibutton(app.ZylasettingsPanel, 'push');
            app.TestwritespeedButton.ButtonPushedFcn = createCallbackFcn(app, @TestwritespeedButtonPushed, true);
            app.TestwritespeedButton.Text = 'Test write speed';
            app.TriggermodeDropDownLabel = uilabel(app.ZylasettingsPanel);
            app.TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.TriggermodeDropDown = uidropdown(app.ZylasettingsPanel);
            app.TriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggermodeDropDownValueChanged, true);
            app.PresetsLabel = uilabel(app.ZylasettingsPanel);
            app.PresetsLabel.Text = 'Presets';
            app.PresetsListBox = uilistbox(app.ZylasettingsPanel);
            app.PresetsListBox.Items = {};
            app.PresetsListBox.ValueChangedFcn = createCallbackFcn(app, @PresetsListBoxValueChanged, true);
            app.PresetsListBox.Value = {};
            app.iXonSettings = uitab(app.SpecificCameraSettings);
            app.iXonsettingsPanel = uipanel(app.iXonSettings);
            app.iXonsettingsPanel.Title = 'iXon settings';
            app.CoolerDropDownLabel = uilabel(app.iXonsettingsPanel);
            app.CoolerDropDownLabel.Text = 'Cooler';
            app.CoolerDropDown = uidropdown(app.iXonsettingsPanel);
            app.CoolerDropDown.ValueChangedFcn = createCallbackFcn(app, @CoolerDropDownValueChanged, true);
            app.ExposuretimeEditFieldLabel_2 = uilabel(app.iXonsettingsPanel);
            app.ExposuretimeEditFieldLabel_2.Text = 'Exposure time';
            app.ExposureTimeiXonEditField = uieditfield(app.iXonsettingsPanel, 'numeric');
            app.ExposureTimeiXonEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeiXonEditFieldValueChanged, true);
            app.CoolermodeDropDownLabel = uilabel(app.iXonsettingsPanel);
            app.CoolermodeDropDownLabel.Text = 'Cooler mode';
            app.CoolermodeiXonDropDown = uidropdown(app.iXonsettingsPanel);
            app.CoolermodeiXonDropDown.ValueChangedFcn = createCallbackFcn(app, @CoolermodeiXonDropDownValueChanged, true);
            app.SensortemperatureEditFieldLabel = uilabel(app.iXonsettingsPanel);
            app.SensortemperatureEditFieldLabel.Text = 'Sensor temperature';
            app.SensortemperatureiXonEditField = uieditfield(app.iXonsettingsPanel, 'numeric');
            app.SensortemperatureiXonEditField.ValueChangedFcn = createCallbackFcn(app, @SensortemperatureiXonEditFieldValueChanged, true);
            app.EmgainmodeDropDownLabel = uilabel(app.iXonsettingsPanel);
            app.EmgainmodeDropDownLabel.Text = 'Em gain mode';
            app.EmgainmodeiXonDropDown = uidropdown(app.iXonsettingsPanel);
            app.EmgainmodeiXonDropDown.ValueChangedFcn = createCallbackFcn(app, @EmgainmodeiXonDropDownValueChanged, true);
            app.EmCCDgainEditFieldLabel = uilabel(app.iXonsettingsPanel);
            app.EmCCDgainEditFieldLabel.Text = 'Em CCD gain';
            app.EmCCDgainiXonEditField = uieditfield(app.iXonsettingsPanel, 'numeric');
            app.EmCCDgainiXonEditField.ValueChangedFcn = createCallbackFcn(app, @EmCCDgainiXonEditFieldValueChanged, true);
            app.FramerateEditField_2Label = uilabel(app.iXonsettingsPanel);
            app.FramerateEditField_2Label.Text = 'Frame rate';
            app.FramerateiXonEditField = uieditfield(app.iXonsettingsPanel, 'numeric');
            app.FramerateiXonEditField.Editable = 'off';
            app.NumberofaccumulationsEditFieldLabel_2 = uilabel(app.iXonsettingsPanel);
            app.NumberofaccumulationsEditFieldLabel_2.Text = 'Number of accumulations';
            app.NumberofaccumulationsiXonEditField = uieditfield(app.iXonsettingsPanel, 'numeric');
            app.NumberofaccumulationsiXonEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofaccumulationsiXonEditFieldValueChanged, true);
            app.VerticalbinningEditFieldLabel_2 = uilabel(app.iXonsettingsPanel);
            app.VerticalbinningEditFieldLabel_2.Text = 'Vertical binning';
            app.VerticalbinningiXonEditField = uieditfield(app.iXonsettingsPanel, 'numeric');
            app.HorisontalbinningEditFieldLabel_2 = uilabel(app.iXonsettingsPanel);
            app.HorisontalbinningEditFieldLabel_2.Text = 'Horisontal binning';
            app.HorisontalbinningiXonEditField = uieditfield(app.iXonsettingsPanel, 'numeric');
            app.AreaofinterestLabeliXon = uilabel(app.iXonsettingsPanel);
            app.AreaofinterestLabeliXon.Text = 'Area of interest';
            app.XpositionSpinner_2Label_2 = uilabel(app.iXonsettingsPanel);
            app.XpositionSpinner_2Label_2.Text = 'X position';
            app.AOIXpositioniXonSpinner = uispinner(app.iXonsettingsPanel);
            app.AOIXpositioniXonSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIXpositioniXonSpinnerValueChanged, true);
            app.YpositionSpinner_2Label = uilabel(app.iXonsettingsPanel);
            app.YpositionSpinner_2Label.Text = 'Y position';
            app.AOIYpositioniXonSpinner = uispinner(app.iXonsettingsPanel);
            app.AOIYpositioniXonSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIYpositioniXonSpinnerValueChanged, true);
            app.WidthSpinner_2Label_2 = uilabel(app.iXonsettingsPanel);
            app.WidthSpinner_2Label_2.Text = 'Width';
            app.AOIWidthiXonSpinner = uispinner(app.iXonsettingsPanel);
            app.AOIWidthiXonSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIWidthiXonSpinnerValueChanged, true);
            app.HeightSpinner_2Label_2 = uilabel(app.iXonsettingsPanel);
            app.HeightSpinner_2Label_2.Text = 'Height';
            app.AOIHeightiXonSpinner = uispinner(app.iXonsettingsPanel);
            app.AOIHeightiXonSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIHeightiXonSpinnerValueChanged, true);
            app.ResetAOIiXonButton = uibutton(app.iXonsettingsPanel, 'push');
            app.ResetAOIiXonButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAOIiXonButtonPushed, true);
            app.ResetAOIiXonButton.Text = 'Reset';
            app.ApplyAOIiXonButton = uibutton(app.iXonsettingsPanel, 'push');
            app.ApplyAOIiXonButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAOIiXonButtonPushed, true);
            app.ApplyAOIiXonButton.Text = 'Apply';
            app.TestWritespeediXonLamp = uilamp(app.iXonsettingsPanel);
            app.TestwritespeediXonButton = uibutton(app.iXonsettingsPanel, 'push');
            app.TestwritespeediXonButton.ButtonPushedFcn = createCallbackFcn(app, @TestwritespeediXonButtonPushed, true);
            app.TestwritespeediXonButton.Text = 'Test write speed';
            app.SimulatorSettings = uitab(app.SpecificCameraSettings);
            app.CamerasimulatorsettingsPanel = uipanel(app.SimulatorSettings);
            app.CamerasimulatorsettingsPanel.Title = 'Camera simulator settings';
            app.NewtonSettings = uitab(app.SpecificCameraSettings);
            app.NewtonsettingsPanel = uipanel(app.NewtonSettings);
            app.NewtonsettingsPanel.Title = 'Newton settings';
            app.AreaofinterestLabelNewton = uilabel(app.NewtonsettingsPanel);
            app.AreaofinterestLabelNewton.Text = 'Area of interest';
            app.ResetAOINewtonButton = uibutton(app.NewtonsettingsPanel, 'push');
            app.ResetAOINewtonButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAOINewtonButtonPushed, true);
            app.ResetAOINewtonButton.Text = 'Reset';
            app.ApplyAOINewtonButton = uibutton(app.NewtonsettingsPanel, 'push');
            app.ApplyAOINewtonButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAOINewtonButtonPushed, true);
            app.ApplyAOINewtonButton.Text = 'Apply';
            app.TestWritespeedNewtonLamp = uilamp(app.NewtonsettingsPanel);
            app.TestwritespeedNewtonButton = uibutton(app.NewtonsettingsPanel, 'push');
            app.TestwritespeedNewtonButton.ButtonPushedFcn = createCallbackFcn(app, @TestwritespeedNewtonButtonPushed, true);
            app.TestwritespeedNewtonButton.Text = 'Test write speed';
            app.CoolerDropDown_2Label = uilabel(app.NewtonsettingsPanel);
            app.CoolerDropDown_2Label.Text = 'Cooler';
            app.CoolerNewtonDropDown = uidropdown(app.NewtonsettingsPanel);
            app.CoolerNewtonDropDown.ValueChangedFcn = createCallbackFcn(app, @CoolerNewtonDropDownValueChanged, true);
            app.ExposuretimeEditFieldLabel_3 = uilabel(app.NewtonsettingsPanel);
            app.ExposuretimeEditFieldLabel_3.Text = 'Exposure time';
            app.ExposureTimeNewtonEditField = uieditfield(app.NewtonsettingsPanel, 'numeric');
            app.ExposureTimeNewtonEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeNewtonEditFieldValueChanged, true);
            app.CoolermodeDropDownLabel_2 = uilabel(app.NewtonsettingsPanel);
            app.CoolermodeDropDownLabel_2.Text = 'Cooler mode';
            app.CoolermodeNewtonDropDown = uidropdown(app.NewtonsettingsPanel);
            app.CoolermodeNewtonDropDown.ValueChangedFcn = createCallbackFcn(app, @CoolermodeNewtonDropDownValueChanged, true);
            app.SensortemperatureEditFieldLabel_2 = uilabel(app.NewtonsettingsPanel);
            app.SensortemperatureEditFieldLabel_2.Text = 'Sensor temperature';
            app.SensortemperatureNewtonEditField = uieditfield(app.NewtonsettingsPanel, 'numeric');
            app.SensortemperatureNewtonEditField.ValueChangedFcn = createCallbackFcn(app, @SensortemperatureNewtonEditFieldValueChanged, true);
            app.FramerateEditField_2Label_2 = uilabel(app.NewtonsettingsPanel);
            app.FramerateEditField_2Label_2.Text = 'Frame rate';
            app.FramerateNewtonEditField = uieditfield(app.NewtonsettingsPanel, 'numeric');
            app.FramerateNewtonEditField.Editable = 'off';
            app.NumberofaccumulationsEditFieldLabel_3 = uilabel(app.NewtonsettingsPanel);
            app.NumberofaccumulationsEditFieldLabel_3.Text = 'Number of accumulations';
            app.NumberofaccumulationsNewtonEditField = uieditfield(app.NewtonsettingsPanel, 'numeric');
            app.NumberofaccumulationsNewtonEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofaccumulationsNewtonEditFieldValueChanged, true);
            app.VerticalbinningEditFieldLabel_3 = uilabel(app.NewtonsettingsPanel);
            app.VerticalbinningEditFieldLabel_3.Text = 'Vertical binning';
            app.VerticalbinningNewtonEditField = uieditfield(app.NewtonsettingsPanel, 'numeric');
            app.HorisontalbinningEditFieldLabel_3 = uilabel(app.NewtonsettingsPanel);
            app.HorisontalbinningEditFieldLabel_3.Text = 'Horisontal binning';
            app.HorisontalbinningNewtonEditField = uieditfield(app.NewtonsettingsPanel, 'numeric');
            app.XpositionSpinner_2Label_3 = uilabel(app.NewtonsettingsPanel);
            app.XpositionSpinner_2Label_3.Text = 'X position';
            app.AOIXpositionNewtonSpinner = uispinner(app.NewtonsettingsPanel);
            app.AOIXpositionNewtonSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIXpositionNewtonSpinnerValueChanged, true);
            app.YpositionSpinner_2Label_2 = uilabel(app.NewtonsettingsPanel);
            app.YpositionSpinner_2Label_2.Text = 'Y position';
            app.AOIYpositionNewtonSpinner = uispinner(app.NewtonsettingsPanel);
            app.AOIYpositionNewtonSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIYpositionNewtonSpinnerValueChanged, true);
            app.WidthSpinner_2Label_3 = uilabel(app.NewtonsettingsPanel);
            app.WidthSpinner_2Label_3.Text = 'Width';
            app.AOIWidthNewtonSpinner = uispinner(app.NewtonsettingsPanel);
            app.AOIWidthNewtonSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIWidthNewtonSpinnerValueChanged, true);
            app.HeightSpinner_2Label_3 = uilabel(app.NewtonsettingsPanel);
            app.HeightSpinner_2Label_3.Text = 'Height';
            app.AOIHeightNewtonSpinner = uispinner(app.NewtonsettingsPanel);
            app.AOIHeightNewtonSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIHeightNewtonSpinnerValueChanged, true);
            app.SensingRegionsPanel = uipanel(app.SettingsTab);
            app.SensingRegionsPanel.Title = 'Sensing Regions';
            app.UIAxesSensingRegionsSettings = uiaxes(app.SensingRegionsPanel);
            xlabel(app.UIAxesSensingRegionsSettings, 'X')
            ylabel(app.UIAxesSensingRegionsSettings, 'Y')
            app.DeleteRegionButton = uibutton(app.SensingRegionsPanel, 'push');
            app.DeleteRegionButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRegionButtonPushed, true);
            app.DeleteRegionButton.Icon = 'glyphicons-halflings-7-bin@2x.png';
            app.DeleteRegionButton.Text = '';
            app.RefreshSensingRegionButton = uibutton(app.SensingRegionsPanel, 'push');
            app.RefreshSensingRegionButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshSensingRegionAxesButtonPushed, true);
            app.RefreshSensingRegionButton.Icon = 'glyphicons-halflings-45-refresh@2x.png';
            app.RefreshSensingRegionButton.Text = '';
            app.ResetZoomSensingRegionAxesButton = uibutton(app.SensingRegionsPanel, 'push');
            app.ResetZoomSensingRegionAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetZoomSensingRegionAxesButtonPushed, true);
            app.ResetZoomSensingRegionAxesButton.Icon = 'glyphicons-halflings-10-home@2x.png';
            app.ResetZoomSensingRegionAxesButton.Text = '';
            app.IsReferenceRegionCheckBox = uicheckbox(app.SensingRegionsPanel);
            app.IsReferenceRegionCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsReferenceRegionCheckBoxValueChanged, true);
            app.IsReferenceRegionCheckBox.Enable = 'off';
            app.IsReferenceRegionCheckBox.Text = 'Is reference region';
            app.IsBackgroundRegionCheckBox = uicheckbox(app.SensingRegionsPanel);
            app.IsBackgroundRegionCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsBackgroundRegionCheckBoxValueChanged, true);
            app.IsBackgroundRegionCheckBox.Enable = 'off';
            app.IsBackgroundRegionCheckBox.Text = 'Is background region';
            app.SelectregionLabel = uilabel(app.SensingRegionsPanel);
            app.SelectregionLabel.Text = 'Select region:';
            app.SelectRegionDropDown = uidropdown(app.SensingRegionsPanel);
            app.SelectRegionDropDown.Items = {'', 'Add region'};
            app.SelectRegionDropDown.Editable = 'on';
            app.SelectRegionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRegionDropDownValueChanged, true);
            app.SelectRegionDropDown.Value = '';
            app.WidthLabel = uilabel(app.SensingRegionsPanel);
            app.WidthLabel.Text = 'Width:';
            app.WidthSpinner = uispinner(app.SensingRegionsPanel);
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthSpinnerValueChanged, true);
            app.WidthSpinner.Enable = 'off';
            app.HeightLabel = uilabel(app.SensingRegionsPanel);
            app.HeightLabel.Text = 'Height:';
            app.HeightSpinner = uispinner(app.SensingRegionsPanel);
            app.HeightSpinner.ValueChangedFcn = createCallbackFcn(app, @HeightSpinnerValueChanged, true);
            app.HeightSpinner.Enable = 'off';
            app.XpositionLabel = uilabel(app.SensingRegionsPanel);
            app.XpositionLabel.Text = 'X position:';
            app.XpositionSpinner = uispinner(app.SensingRegionsPanel);
            app.XpositionSpinner.ValueChangedFcn = createCallbackFcn(app, @XpositionSpinnerValueChanged, true);
            app.XpositionSpinner.Enable = 'off';
            app.YpositionLabel = uilabel(app.SensingRegionsPanel);
            app.YpositionLabel.Text = 'Y position:';
            app.YpositionSpinner = uispinner(app.SensingRegionsPanel);
            app.YpositionSpinner.ValueChangedFcn = createCallbackFcn(app, @YpositionSpinnerValueChanged, true);
            app.YpositionSpinner.Enable = 'off';
            app.ReferenceLabel = uilabel(app.SensingRegionsPanel);
            app.ReferenceLabel.Text = 'Reference:';
            app.ReferenceRegionsListBox = uilistbox(app.SensingRegionsPanel);
            app.ReferenceRegionsListBox.Items = {};
            app.ReferenceRegionsListBox.Multiselect = 'on';
            app.ReferenceRegionsListBox.ValueChangedFcn = createCallbackFcn(app, @ReferenceRegionsListBoxValueChanged, true);
            app.ReferenceRegionsListBox.Enable = 'off';
            app.ReferenceRegionsListBox.Value = {};
            app.BackgroundLabel = uilabel(app.SensingRegionsPanel);
            app.BackgroundLabel.Text = 'Background:';
            app.BackgroundRegionsListBox = uilistbox(app.SensingRegionsPanel);
            app.BackgroundRegionsListBox.Items = {};
            app.BackgroundRegionsListBox.Multiselect = 'on';
            app.BackgroundRegionsListBox.ValueChangedFcn = createCallbackFcn(app, @BackgroundRegionsListBoxValueChanged, true);
            app.BackgroundRegionsListBox.Enable = 'off';
            app.BackgroundRegionsListBox.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FrameAnalysisinRealTimeRTFAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FrameAnalysisinRealTimeRTFAUIFigure)
        end
    end
end